classdef Iamge_Lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        Slider_2         matlab.ui.control.Slider
        Label_3          matlab.ui.control.Label
        Button_2         matlab.ui.control.Button
        DropDown         matlab.ui.control.DropDown
        Label            matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        Button           matlab.ui.control.Button
        title2           matlab.ui.control.Label
        title1           matlab.ui.control.Label
        Button_B_filter  matlab.ui.control.Button
        Button_choose    matlab.ui.control.Button
        Button_exit      matlab.ui.control.Button
        Button_about     matlab.ui.control.Button
        background       matlab.ui.control.Image
        imgOrignal       matlab.ui.control.UIAxes
        imgDel           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % 现存的图像
        imgafter % 处理后的图像
        img_original % 原始图像
        imgisExist=0 % 当前待处理的图片是否有效/是否存在(默认不存在\不有效)
        imgbedone=0;
        img_empty=imread("UI/empty_img.jpg"); % 当保存后展示在imgdel中的空图片
        duibidu2 = 0.02
        duibidu1 = 0.01
    end
    
    methods (Access = private)
        function out = B_filter(app,Img,tempsize,sigma0,sigma1)
            %模板定义
            gauss = fspecial('gauss',2*tempsize+1,sigma0);
            app.Button_choose
            [m,n] = size(Img);
            
            for i = 1+ tempsize : m - tempsize
                for j = 1+ tempsize : n - tempsize
                    %提取处理区域得到梯度敏感矩阵
                    temp = abs(Img(i - tempsize:i + tempsize,j - tempsize:j + tempsize) - Img(i,j));
                    temp = exp(-temp.^2/(2*sigma1^2));
                    %得到双边滤波器并将权值和化为一
                    filter = gauss.*temp;
                    filter = filter/sum(filter(:));
                    %卷积
                    Img(i,j) = sum(sum((Img(i - tempsize:i + tempsize,j - tempsize:j + tempsize).*filter)));
                end
            end

            out = Img;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_about
        function Button_aboutPushed(app, event)
            %关于按钮的提示框 
            msgbox("这是一个美颜app,版本为0.12","关于 ",'help');
        end

        % Button pushed function: Button_exit
        function Button_exitPushed(app, event)
            %关闭程序的选项
            choise = questdlg("是否需要关闭程序","退出程序","退出","不退出","不退出");
            %判断是否关闭
            switch choise
                case "退出"
                    delete(app.UIFigure);
                    return;
                case "不退出"
                    return;
            end
        end

        % Button pushed function: Button_choose
        function Button_choosePushed(app, event)
            [filename,pathname] = uigetfile({'*.jpg';'*.bmp';'*.tif'},"选择图像");
            if isequal(filename,0) || isequal(pathname,0)%如果没有这个文件
%                 errordlg("没有选中文件","错误");
                  
                  app.imgisExist=0;
            else
                fullFilename = fullfile(pathname,filename);
                app.imgisExist=1;
                app.imgbedone=1;
            end
           if app.imgisExist==1
            app.img = imread(fullFilename);
            imshow(app.img,"parent",app.imgOrignal);%将图片展示到应用中
            app.img_original = app.img;
            imshow(app.img_empty,'Parent',app.imgDel);
            msgbox("读取成功","OvO v");
           else if app.imgisExist==0&&app.imgbedone==0%不变成牛爷爷
               imshow("simpbox\Niu.jpg","Parent",app.imgOrignal);
           end
           end
        end

        % Button pushed function: Button_B_filter
        function Button_B_filterPushed(app, event)
            %暂时处理完毕
            if(app.imgisExist==0)%如果当前app.img没有图像/没有有效图像
                errordlg("没有选中文件","错误");
                return;
            end
            tempsize = 5;
            sigma1 = app.Slider.Value;%主导参数(默认值为10)
            sigma2 = 0.2;%主导参数

            
            img_del = double(padarray(app.img,[tempsize,tempsize],0))/255;
            %通道提取
            imgr = img_del(:,:,1);
            imgg = img_del(:,:,2);
            imgb = img_del(:,:,3);

            [m,n] = size(imgr);

            %通道处理1
            img_del(:,:,1) = B_filter(app,imgr,tempsize,sigma1,sigma2);
            img_del(:,:,2) = B_filter(app,imgg,tempsize,sigma1,sigma2);
            img_del(:,:,3) = B_filter(app,imgb,tempsize,sigma1,sigma2);
            app.imgafter=img_del(tempsize+1:m-tempsize,tempsize+1:n-tempsize,:);
            
            imshow(app.imgafter,"Parent",app.imgDel);
            choise = questdlg("是否需要要储存当前步骤处理后的图片","提示","保存","不保存","不保存");
            switch choise
                case "保存"
         
                    app.img = uint8(app.imgafter*255);
                    imshow(app.img,"parent",app.imgOrignal);
                    imshow(app.img_empty,"parent",app.imgDel);
                    return;
                case "不保存"
                    return;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

            if(app.imgisExist==0&&app.imgbedone==0)%如果当前app.img没有图像/没有有效图像
                errordlg("没有选中文件","错误");
                return;
            end
            value = app.DropDown.Value;
            app.imgafter = uint8(zeros(size(app.img)));
            switch value
                case '选择滤镜'
                    app.imgafter = app.img;
                case '怀旧滤镜'

                    T = im2double(app.img);
                    app.imgafter = im2double(app.imgafter);
                    [m,n,~] = size(T);
                    for i=1:m
                        for j=1:n
                            app.imgafter(i,j,1) = 0.393*T(i,j,1)+0.769*T(i,j,2)+0.189*T(i,j,3);
                            app.imgafter(i,j,2) = 0.349*T(i,j,1)+0.686*T(i,j,2)+0.168*T(i,j,3);
                            app.imgafter(i,j,3) = 0.272*T(i,j,1)+0.534*T(i,j,2)+0.131*T(i,j,3);
                        end
                    end
                    app.imgafter=uint8(app.imgafter*255);
                case '冲印效果'
%                     
                    T = app.img;
                    [m,n,~] = size(T);
                    %uint8
                    for i=1:m
                        for j=1:n
                            if T(i,j,1)<128
                                app.imgafter(i,j,1) = uint8(uint16(T(i,j,1)) * uint16(T(i,j,1)) * uint16(T(i,j,1))/ 16384);
                            else
                                app.imgafter(i,j,1) = uint8(256 - (256 - uint16(T(i,j,1))) * (256 - uint16(T(i,j,1))) * (256 - uint16(T(i,j,1))) / 16384);
                            end

                            if T(i,j,2)<128
                                app.imgafter(i,j,2) = uint8(uint16(T(i,j,2)) * uint16(T(i,j,2)) / 128);
                            else
                                app.imgafter(i,j,2) = uint8(256 - (256 - uint16(T(i,j,2))) * (256 - uint16(T(i,j,2))) / 128);
                            end

                            app.imgafter(i,j,3) = uint8(uint16(T(i,j,3)) / 2 + 37);
                        end
                    end
                case '素描效果'
                    [m,n,~] = size(app.img);
                    app.imgafter = im2gray(app.imgafter);
                    %素描效果uint8
                    T = rgb2gray(app.img);%转为灰度图
                    T1 = 255 - T;%反色
                    w = fspecial('gaussian',[5 5],5);%构造一个高斯滤波器
                    T2 = imfilter(T1,w);%高斯模糊
                    T3 = uint8(zeros(m,n));

                    %模糊后的图像叠加模式选择颜色减淡效果。
                    for i=1:m
                        for j=1:n
                            T3(i,j) = uint8(min(uint16(T(i,j)) + (uint16(T(i,j))*uint16(T2(i,j))) / (255 - uint16(T2(i,j))),255));
                            app.imgafter(i,j,1) = T3(i,j);
                            app.imgafter(i,j,2) = T3(i,j);
                            app.imgafter(i,j,3) = T3(i,j);
                        end
                    end
                case '黑白风格'
                    T = app.img;
                    [m,n,~] = size(T);

                    %黑白风格uint8
                    for i=1:m
                        for j=1:n
                            app.imgafter(i,j,1) = uint8(abs(uint16(T(i,j,2))-uint16(T(i,j,3))+uint16(T(i,j,2))+uint16(T(i,j,1))) * uint16(T(i,j,1)) / 256);
                            app.imgafter(i,j,2) = uint8(abs(uint16(T(i,j,3))-uint16(T(i,j,2))+uint16(T(i,j,3))+uint16(T(i,j,1))) * uint16(T(i,j,1)) / 256);
                            app.imgafter(i,j,3) = uint8(abs(uint16(T(i,j,3))-uint16(T(i,j,2))+uint16(T(i,j,3))+uint16(T(i,j,1))) * uint16(T(i,j,2)) / 256);
                        end
                    end
                    app.imgafter = rgb2gray(app.imgafter);%转为灰度图
                case '细节锐化'
                    T = app.img;
                    H=[-1 -1 -1;-1 8 -1;-1 -1 -1]; %Laplacian锐化模板
                    
                    app.imgafter(:,:,:)=imfilter(T(:,:,:),H);
                    app.imgafter = im2double(app.imgafter);
                    app.imgafter=app.imgafter/3+im2double(T);
            end
    
            imshow(app.imgafter,"Parent",app.imgDel);
            choise = questdlg("是否需要要储存当前步骤处理后的图片","提示","保存","不保存","不保存");
            switch choise
                case "保存"
                    app.img = app.imgafter;
                    imshow(app.img,"parent",app.imgOrignal);
                    imshow(app.img_empty,"parent",app.imgDel);
                    msgbox("保存成功","OvO v");
                    return;
                case "不保存"
                    return;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.imgisExist==0)%如果当前app.img没有图像/没有有效图像
                errordlg("没有选中文件","错误");
                return;
            end
            imshow(app.img_original,'Parent',app.imgOrignal);
            imshow(app.img_empty,'Parent',app.imgDel);
            app.img = app.img_original;
           
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

            if(app.imgisExist==0)
                errordlg('当前没有图片哦！qwq','出错');
                return;
            end
            [filename,pathname] = uiputfile({'*.jpg','JPEG(*.jpg)';...
                '*.bmp','Bitmap(*.bmp)';...
                '*.gif','GIF(*.gif)';...
                '*.*',  'All Files (*.*)'},...
                'Save Picture','Untitled');
            if isequal([filename,pathname],[0,0])
                errordlg('保存失败','出错');
                return;
            else
                file = strcat(pathname,filename);
                imwrite(app.img,file);
                msgbox("图片保存成功","OvO v");
            end
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.duibidu1 = event.Value/100;
            app.duibidu2 = app.duibidu1+0.2;
            if app.duibidu2 >= 1
                app.duibidu2 = 1;
            end
            T=im2double(app.img);
            app.imgafter=imadjust(T(:,:,:),[0.01 app.duibidu2],[0 1]);%将0.2-0.5之间的灰度扩展到整个0-1范围，这种处理 对于强调感兴趣灰度区非常有用
            imshow(app.imgafter,"Parent",app.imgDel);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.imgDel = uiaxes(app.GridLayout);
            title(app.imgDel, '  ')
            xlabel(app.imgDel, 'X')
            ylabel(app.imgDel, 'Y')
            zlabel(app.imgDel, 'Z')
            app.imgOrignal = uiaxes(app.GridLayout);
            title(app.imgOrignal, '  ')
            xlabel(app.imgOrignal, 'X')
            ylabel(app.imgOrignal, 'Y')
            zlabel(app.imgOrignal, 'Z')
            app.background = uiimage(app.GridLayout);
            app.background.ImageSource = fullfile(pathToMLAPP, 'UI', 'background.jpg');
            app.Button_about = uibutton(app.GridLayout, 'push');
            app.Button_about.ButtonPushedFcn = createCallbackFcn(app, @Button_aboutPushed, true);
            app.Button_about.Icon = fullfile(pathToMLAPP, 'UI', 'about_ui.jpg');
            app.Button_about.Tooltip = {'这是一个demo'};
            app.Button_about.Text = '关于';
            app.Button_exit = uibutton(app.GridLayout, 'push');
            app.Button_exit.ButtonPushedFcn = createCallbackFcn(app, @Button_exitPushed, true);
            app.Button_exit.Icon = fullfile(pathToMLAPP, 'UI', 'Exit_ui.jpg');
            app.Button_exit.Tooltip = {'退出程序QAQ'};
            app.Button_exit.Text = '退出';
            app.Button_choose = uibutton(app.GridLayout, 'push');
            app.Button_choose.ButtonPushedFcn = createCallbackFcn(app, @Button_choosePushed, true);
            app.Button_choose.Icon = fullfile(pathToMLAPP, 'UI', 'choose_ui.jpg');
            app.Button_choose.Tooltip = {'选择你想要处理的图片吧'};
            app.Button_choose.Text = '选择图片';
            app.Button_B_filter = uibutton(app.GridLayout, 'push');
            app.Button_B_filter.ButtonPushedFcn = createCallbackFcn(app, @Button_B_filterPushed, true);
            app.Button_B_filter.Icon = fullfile(pathToMLAPP, 'UI', 'beauty_ui.jpg');
            app.Button_B_filter.Text = '一键祛痘';
            app.title1 = uilabel(app.GridLayout);
            app.title1.Text = '当前图像';
            app.title2 = uilabel(app.GridLayout);
            app.title2.Text = '预览图像';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'UI', 'reset_ui.jpg');
            app.Button.Text = '恢复原图';
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [10 100];
            app.Slider.Value = 10;
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '祛斑程度';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '人物滤镜';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'选择滤镜', '怀旧滤镜', '冲印效果', '素描效果', '黑白风格', '细节锐化'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '选择滤镜';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'UI', 'Filter_ui.jpg');
            app.Button_2.Text = '将图片保存为';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '调整对比度';
            app.Slider_2 = uislider(app.GridLayout);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Iamge_Lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end